classdef AWFOM_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ImportSaveFileButton           matlab.ui.control.Button
        StatusTextArea                 matlab.ui.control.TextArea
        StatusTextAreaLabel            matlab.ui.control.Label
        ViewResultsButton              matlab.ui.control.Button
        SelectsolversforcomparisonLabel  matlab.ui.control.Label
        SurrogateOptimisationCheckBox  matlab.ui.control.CheckBox
        GlobalSearchCheckBox           matlab.ui.control.CheckBox
        SimulatedAnnealingCheckBox     matlab.ui.control.CheckBox
        ParticleSwarmCheckBox          matlab.ui.control.CheckBox
        patternsearchCheckBox          matlab.ui.control.CheckBox
        fminconCheckBox                matlab.ui.control.CheckBox
        SolverOptionsButton            matlab.ui.control.Button
        WindFarmParameterCreationButton  matlab.ui.control.Button
        ExportSaveFileButton           matlab.ui.control.Button
        RunOptimisationforcurrentfarmButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        
        function set_current_file(app, f)
            
            msg = strcat('Current parameters/results file is: ',{''},f);
            app.StatusTextArea.Value = msg;
            app.StatusTextArea.UserData = f;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            addpath(genpath('../AWFOM'));  
            f = fullfile('params.mat');
            set_current_file(app, f);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: RunOptimisationforcurrentfarmButton
        function RunOptimisationforcurrentfarmButtonPushed(app, event)
            selected_solvers = [app.fminconCheckBox.Value, ...
                                app.patternsearchCheckBox.Value, ...
                                app.ParticleSwarmCheckBox.Value, ...
                                app.GlobalSearchCheckBox.Value, ...
                                app.SimulatedAnnealingCheckBox.Value, ...
                                app.SurrogateOptimisationCheckBox.Value];

            if sum(selected_solvers) == 0

                errordlg('Please select a solver using the checkboxes',...
                         'Insufficient Solver Selection')
                return    
            end
            
            farm_optimiser(selected_solvers, app.StatusTextArea);
        end

        % Button pushed function: WindFarmParameterCreationButton
        function WindFarmParameterCreationButtonPushed(app, event)
            waitfor(AWFOM_farmgen)
        end

        % Button pushed function: SolverOptionsButton
        function SolverOptionsButtonPushed(app, event)
            waitfor(AWFOM_solveropts(app.fminconCheckBox.Value, ...
                                     app.patternsearchCheckBox.Value, ...                                     
                                     app.ParticleSwarmCheckBox.Value, ...
                                     app.GlobalSearchCheckBox.Value, ...
                                     app.SimulatedAnnealingCheckBox.Value, ...                                     
                                     app.SurrogateOptimisationCheckBox.Value))
        end

        % Button pushed function: ViewResultsButton
        function ViewResultsButtonPushed(app, event)
            waitfor(AWFOM_results)
        end

        % Button pushed function: ImportSaveFileButton
        function ImportSaveFileButtonPushed(app, event)
            % open a window where the user can select an optimisation case
            % create a dummy figure so that uigetfile doesn't minimize GUI
            dummy = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path] = uigetfile('*.mat');
            %delete dummy figure
            delete(dummy);
            file_loc = fullfile(path,file);
            % if the user selects a file do the following
            if file ~=  0 
                check_params = whos('-file', file_loc);
                if strcmp(check_params.name, 'params') == 1
                    load(file_loc, 'params');
                    save("params.mat", "params")
                else
                    errordlg('Please select a valid parameters file', 'File Error');               
                end
            end
            
            %set the values in the window to the values in the selected
            %file
            set_current_file(app, file_loc)
        end

        % Button pushed function: ExportSaveFileButton
        function ExportSaveFileButtonPushed(app, event)
            dummy = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            load("params.mat", "params");
            uisave('params', app.StatusTextArea.UserData);
            %delete dummy figure
            delete(dummy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RunOptimisationforcurrentfarmButton = uibutton(app.UIFigure, 'push');
            app.RunOptimisationforcurrentfarmButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimisationforcurrentfarmButtonPushed, true);
            app.RunOptimisationforcurrentfarmButton.Text = 'Run Optimisation for current farm';
            app.ExportSaveFileButton = uibutton(app.UIFigure, 'push');
            app.ExportSaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSaveFileButtonPushed, true);
            app.ExportSaveFileButton.Text = 'Export Save File';
            app.WindFarmParameterCreationButton = uibutton(app.UIFigure, 'push');
            app.WindFarmParameterCreationButton.ButtonPushedFcn = createCallbackFcn(app, @WindFarmParameterCreationButtonPushed, true);
            app.WindFarmParameterCreationButton.Text = 'Wind Farm Parameter Creation';
            app.SolverOptionsButton = uibutton(app.UIFigure, 'push');
            app.SolverOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @SolverOptionsButtonPushed, true);
            app.SolverOptionsButton.Text = 'Solver Options';
            app.fminconCheckBox = uicheckbox(app.UIFigure);
            app.fminconCheckBox.Text = 'fmincon';
            app.patternsearchCheckBox = uicheckbox(app.UIFigure);
            app.patternsearchCheckBox.Text = 'patternsearch';
            app.ParticleSwarmCheckBox = uicheckbox(app.UIFigure);
            app.ParticleSwarmCheckBox.Text = 'Particle Swarm';
            app.SimulatedAnnealingCheckBox = uicheckbox(app.UIFigure);
            app.SimulatedAnnealingCheckBox.Enable = 'off';
            app.SimulatedAnnealingCheckBox.Text = 'Simulated Annealing';
            app.GlobalSearchCheckBox = uicheckbox(app.UIFigure);
            app.GlobalSearchCheckBox.Enable = 'off';
            app.GlobalSearchCheckBox.Text = 'Global Search';
            app.SurrogateOptimisationCheckBox = uicheckbox(app.UIFigure);
            app.SurrogateOptimisationCheckBox.Enable = 'off';
            app.SurrogateOptimisationCheckBox.Text = 'Surrogate Optimisation';
            app.SelectsolversforcomparisonLabel = uilabel(app.UIFigure);
            app.SelectsolversforcomparisonLabel.Text = 'Select solvers for comparison';
            app.ViewResultsButton = uibutton(app.UIFigure, 'push');
            app.ViewResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewResultsButtonPushed, true);
            app.ViewResultsButton.Text = 'View Results';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.ImportSaveFileButton = uibutton(app.UIFigure, 'push');
            app.ImportSaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSaveFileButtonPushed, true);
            app.ImportSaveFileButton.Text = 'Import Save File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end