classdef AWFOM_farmgen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        rhoEditField                   matlab.ui.control.NumericEditField
        rhokgm3EditFieldLabel          matlab.ui.control.Label
        sortlocsCheckBox               matlab.ui.control.CheckBox
        LoadFarmParametersfromsavefileButton  matlab.ui.control.Button
        CHOOSEANALYSISButton           matlab.ui.control.Button
        WindDirectiondegreesEditField  matlab.ui.control.NumericEditField
        WindDirectiondegreesEditFieldLabel  matlab.ui.control.Label
        WindSpeedmsEditField           matlab.ui.control.NumericEditField
        WindSpeedmsEditFieldLabel      matlab.ui.control.Label
        ImportTurbineLocationsButton   matlab.ui.control.Button
        GenerateRandomTurbineLocationsButton  matlab.ui.control.Button
        SaveandReturnButton            matlab.ui.control.Button
        SetRotorDiametermEditField     matlab.ui.control.NumericEditField
        SetRotorDiametermEditFieldLabel  matlab.ui.control.Label
        SelectTurbinePowerCurveButton  matlab.ui.control.Button
        TurbineLocations               matlab.ui.control.UIAxes
        PwrCrv                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("params.mat",'params');

            app.WindSpeedmsEditField.Value = params.env.wind_speed;
            app.WindDirectiondegreesEditField.Value = params.env.wind_direction;
            app.SetRotorDiametermEditField.Value = 80;


            %plot exising turbine locations          
            turb_cents = params.farm.turbine_centres;
            turb_cents_x = turb_cents(:,1);
            turb_cents_y = turb_cents(:,2);
                        
            scatter(app.TurbineLocations,turb_cents_x, turb_cents_y)
            
            %plot existing power curve
            pwr_c = params.turb.power_curve;                   
            w_speed_plot = linspace(min(pwr_c(:,1)), max(pwr_c(:,1)), max(pwr_c(:,1))-min(pwr_c(:,1)));
            pwr_plot = interp1(pwr_c(:,1), pwr_c(:,2), w_speed_plot);
            plot(app.PwrCrv,w_speed_plot, pwr_plot,'-r');

            save('temp_params.mat',"params"); 
        end

        % Button pushed function: SelectTurbinePowerCurveButton
        function SelectTurbinePowerCurveButtonPushed(app, event)
            load("temp_params.mat",'params');
            % open a window where the user can select a power curve
            %create a dummy figure so that uigetfile doesn't minimize GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path] = uigetfile('*.csv');
            %delete dummy figure
            delete(f);


            % if the user selects a file do the following
            if file ~=  0
               
            %read the selected power curve into a matrix
            power_curve = readmatrix(fullfile(path,file));
            
                %check that the power curve is valid for input into floris
                if size(power_curve,2) == 2 && size(power_curve,1) > 1
               
                    params.turb.power_curve = power_curve;
                    %plot updated power curve
                    pwr_c = params.turb.power_curve;                     
                    w_speed_plot = linspace(min(pwr_c(:,1)), max(pwr_c(:,1)), max(pwr_c(:,1))-min(pwr_c(:,1)));
                    pwr_plot = interp1(pwr_c(:,1), pwr_c(:,2), w_speed_plot);
                    plot(app.PwrCrv,w_speed_plot, pwr_plot,'-r');
                
                %if the power curve data isn't valid return an error
                else
                    errordlg('Power curve must be a Nx2 matrix', 'File Error');
                    
                end
            
           end
           save('temp_params.mat',"params"); 
        end

        % Button pushed function: GenerateRandomTurbineLocationsButton
        function GenerateRandomTurbineLocationsButtonPushed(app, event)
            %run random turbine location generator
            waitfor(AWFOM_locgen)
            
            load("temp_params.mat",'params');
            
            %repeat startup function
            %plot exising turbine locations          
            turb_cents = params.farm.turbine_centres;
            turb_cents_x = turb_cents(:,1);
            turb_cents_y = turb_cents(:,2);
                        
            scatter(app.TurbineLocations,turb_cents_x, turb_cents_y)
            %plot exising turbine locations
            

            %store values in temporary 
            save('temp_params.mat',"params"); 
        end

        % Button pushed function: SaveandReturnButton
        function SaveandReturnButtonPushed(app, event)
            %save values from in-window fields 
            load('temp_params.mat',"params");
            params.env.wind_direction = app.WindDirectiondegreesEditField.Value;
            params.env.wind_speed = app.WindSpeedmsEditField.Value;
            params.env.density = app.rhoEditField.Value;
            params.turb.diameters = app.SetRotorDiametermEditField.Value;

            if app.sortlocsCheckBox.Value == 1
                params.globcon.sortlocs = 1;
            else
                params.globcon.sortlocs = 0;
            end

            save('temp_params.mat', 'params');
            

            %check dimensions are consistent for important parameters and
            %exit save and return if they aren't
            load('temp_params.mat', 'params');
            n_turb = params.farm.n_turb;
            
            if size(params.farm.turbine_centres,1) ~= n_turb
               
               errordlg(['Dimensions of farm parameter matrices are inconsistent,' ...
                   'Make sure you use the Save and Return buttons to apply changes'],...
                   'Farm Creation Error')
               return
            end

            %having checked for consistent dimensions, convert starting
            %point values to vectors so  they can be used in optimisation
            conv_success = convert_globcon('temp_params.mat');

            load('temp_params.mat', 'params');
               
            if conv_success == 0
                errordlg(['Error in parameter creation' ...
                   'Try the big button that says "CHOOSE ANALYSIS"'],...
                   'Farm Creation Error')
                return
            end

%             %if the user has not selected their initial conditions            
%             if size(params.globcon.ub,1) ~= n_turb(1) || ...
%                size(params.globcon.yaw_init,1) ~= n_turb(1)
%                 errordlg(['You must an analysis type for the farm' ...
%                    'Try the big button that says "CHOOSE ANALYSIS"'],...
%                    'Farm Creation Error')
%                 return
%             end            


            save('params.mat',"params");
            %delete temp file and close window
            %delete('temp_params.mat');
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete the temp params file when the window is close
            %delete('temp_params.mat');
            delete(app)            
        end

        % Button pushed function: CHOOSEANALYSISButton
        function CHOOSEANALYSISButtonPushed(app, event)
            waitfor(AWFOM_inityaw)
        end

        % Button pushed function: ImportTurbineLocationsButton
        function ImportTurbineLocationsButtonPushed(app, event)
            load("temp_params.mat",'params');
            % open a window where the user can select a power curve
            %create a dummy figure so that uigetfile doesn't minimize GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path] = uigetfile('*.csv');
            %delete dummy figure
            delete(f);


            % if the user selects a file do the following
            if file ~=  0
               
            %read the selected power curve into a matrix
            farm_layout= readmatrix(fullfile(path,file));
            
                %check that the farm layout is valid for input into floris
                if size(farm_layout,2) == 2 && size(farm_layout,1) > 0
                    
                    %save parameters for the number of turbines in the
                    %farm and their locations on a flat plane at z=0
                    farm_size_x = ceil(max(farm_layout(:,1))/1000)*1000;
                    farm_size_y = ceil(max(farm_layout(:,2))/1000)*1000;
                    
                    
                    n_turb = size(farm_layout, 1);
                    turb_centres = zeros(n_turb, 3);
                    turb_centres(:,1:2) = farm_layout;

                    params.farm.turbine_centres = turb_centres;
                    params.farm.farm_size = [farm_size_x, farm_size_y];
                    params.farm.n_turb = n_turb;

                %if the farm layout data isn't valid return an error
                else
                    errordlg(['Turbine locations file must be a .csv file containing an Nx2 matrix ...' ...
                              'of the x and y positions for the turbine faces'], 'File Error');
                    
                end
            
           end


           %repeat startup function
           %plot exising turbine locations          
           turb_cents = params.farm.turbine_centres;
           turb_cents_x = turb_cents(:,1);
           turb_cents_y = turb_cents(:,2);

           %plot exising turbine locations             
           scatter(app.TurbineLocations,turb_cents_x, turb_cents_y)           

           %store values in temporary 
           save('temp_params.mat',"params"); 
        end

        % Button pushed function: LoadFarmParametersfromsavefileButton
        function LoadFarmParametersfromsavefileButtonPushed(app, event)
            % open a window where the user can select an optimisation case
            % create a dummy figure so that uigetfile doesn't minimize GUI
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path] = uigetfile('*.mat');
            %delete dummy figure
            delete(f);
            file_loc = fullfile(path,file);
            % if the user selects a file do the following
            if file ~=  0 
                check_params = whos('-file', file_loc);
                if strcmp(check_params.name, 'params') == 1
                    load(file_loc, 'params');
                    save("params.mat", "params")
                else
                    errordlg('Please select a valid parameters file', 'File Error');               
                end
            end
            
            %set the values in the window to the values in the selected
            %file
            startupFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PwrCrv = uiaxes(app.UIFigure);
            title(app.PwrCrv, 'Power Curve for Selected Turbine')
            xlabel(app.PwrCrv, 'Wind Speed (m/s)')
            ylabel(app.PwrCrv, {'Turbine'; 'Power Output'; '(W)'})
            app.TurbineLocations = uiaxes(app.UIFigure);
            title(app.TurbineLocations, 'Turbine Locations')
            app.SelectTurbinePowerCurveButton = uibutton(app.UIFigure, 'push');
            app.SelectTurbinePowerCurveButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTurbinePowerCurveButtonPushed, true);
            app.SelectTurbinePowerCurveButton.Text = 'Select Turbine Power Curve';
            app.SetRotorDiametermEditFieldLabel = uilabel(app.UIFigure);
            app.SetRotorDiametermEditFieldLabel.Text = 'Set Rotor Diameter (m)';
            app.SetRotorDiametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetRotorDiametermEditField.Value = 80;
            app.SaveandReturnButton = uibutton(app.UIFigure, 'push');
            app.SaveandReturnButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandReturnButtonPushed, true);
            app.SaveandReturnButton.Text = 'Save and Return';
            app.GenerateRandomTurbineLocationsButton = uibutton(app.UIFigure, 'push');
            app.GenerateRandomTurbineLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomTurbineLocationsButtonPushed, true);
            app.GenerateRandomTurbineLocationsButton.Text = 'Generate Random Turbine Locations';
            app.ImportTurbineLocationsButton = uibutton(app.UIFigure, 'push');
            app.ImportTurbineLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTurbineLocationsButtonPushed, true);
            app.ImportTurbineLocationsButton.Text = 'Import Turbine Locations ';
            app.WindSpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.WindSpeedmsEditFieldLabel.Text = 'Wind Speed (m/s)';
            app.WindSpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindSpeedmsEditField.Limits = [0 30];
            app.WindSpeedmsEditField.Value = 20;
            app.WindDirectiondegreesEditFieldLabel = uilabel(app.UIFigure);
            app.WindDirectiondegreesEditFieldLabel.Text = 'Wind Direction (degrees)';
            app.WindDirectiondegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindDirectiondegreesEditField.Limits = [0 360];
            app.WindDirectiondegreesEditField.RoundFractionalValues = 'on';
            app.WindDirectiondegreesEditField.Value = 180;
            app.CHOOSEANALYSISButton = uibutton(app.UIFigure, 'push');
            app.CHOOSEANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @CHOOSEANALYSISButtonPushed, true);
            app.CHOOSEANALYSISButton.Text = 'CHOOSE ANALYSIS';
            app.LoadFarmParametersfromsavefileButton = uibutton(app.UIFigure, 'push');
            app.LoadFarmParametersfromsavefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFarmParametersfromsavefileButtonPushed, true);
            app.LoadFarmParametersfromsavefileButton.Text = {'Load Farm Parameters '; 'from save file'};
            app.sortlocsCheckBox = uicheckbox(app.UIFigure);
            app.sortlocsCheckBox.Text = 'Sort locations by proximity to windfront';
            app.rhokgm3EditFieldLabel = uilabel(app.UIFigure);
            app.rhokgm3EditFieldLabel.Interpreter = 'latex';
            app.rhokgm3EditFieldLabel.Text = '\(\rho\) (kg/m\(^{3}\))';
            app.rhoEditField = uieditfield(app.UIFigure, 'numeric');
            app.rhoEditField.Limits = [0.95 1.8];
            app.rhoEditField.Value = 1.225;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_farmgen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end