classdef AWFOM_inityaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        farmsz_CheckBox               matlab.ui.control.CheckBox
        case_nEditField               matlab.ui.control.NumericEditField
        NumberofstartpointstoevaluateLabel  matlab.ui.control.Label
        SaveandReturnButton           matlab.ui.control.Button
        CheckBox_RandomYaws           matlab.ui.control.CheckBox
        windsweep_CheckBox            matlab.ui.control.CheckBox
        CheckBox_EqualYaws            matlab.ui.control.CheckBox
        MaxYawAngleEditField          matlab.ui.control.NumericEditField
        MaximumYawLabel               matlab.ui.control.Label
        ChooseoptimisationconstraintsandFarmStatestoevaluateLabel  matlab.ui.control.Label
        farmsz_Panel                  matlab.ui.container.Panel
        NOTEEnablingthiswilldisregardthelayoutsetbytheuserLabel  matlab.ui.control.Label
        mbetweenturbinesLabel         matlab.ui.control.Label
        turbspac_EditField            matlab.ui.control.NumericEditField
        withaspacingofEditFieldLabel  matlab.ui.control.Label
        nmax_EditField                matlab.ui.control.NumericEditField
        toEditField_2Label_2          matlab.ui.control.Label
        nmin_EditField                matlab.ui.control.NumericEditField
        IncreaseNfromEditFieldLabel   matlab.ui.control.Label
        ForanNxNgridwhereNrepresentsthenumberofturbinesLabel  matlab.ui.control.Label
        wdsweep_Panel                 matlab.ui.container.Panel
        circLabel                     matlab.ui.control.Label
        circLabel_2                   matlab.ui.control.Label
        maxwd_EditField               matlab.ui.control.NumericEditField
        toEditField_2Label            matlab.ui.control.Label
        minwd_EditField               matlab.ui.control.NumericEditField
        SweepincomingwinddirectionforthefarmfromLabel  matlab.ui.control.Label
        AllStartingYawsEqualPanel     matlab.ui.container.Panel
        MultipleStartingPointParametersPanel_Eql  matlab.ui.container.Panel
        toEditField                   matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        YawsfromEditField             matlab.ui.control.NumericEditField
        YawsfromEditFieldLabel        matlab.ui.control.Label
        SingleStartPointParametersPanel_Eql  matlab.ui.container.Panel
        YawSingleEditField            matlab.ui.control.NumericEditField
        YawdegreesEditFieldLabel      matlab.ui.control.Label
        MultiplicityButtonGroup_Eql   matlab.ui.container.ButtonGroup
        MultipleButton_Eql            matlab.ui.control.RadioButton
        SingleButton_Eql              matlab.ui.control.RadioButton
        NormallyDistributedStartingYawsPanel  matlab.ui.container.Panel
        RandomGenerationParametersPanel_Rnd  matlab.ui.container.Panel
        sigmaEditField                matlab.ui.control.NumericEditField
        sigmaEditFieldLabel           matlab.ui.control.Label
        muEditField                   matlab.ui.control.NumericEditField
        muLabel                       matlab.ui.control.Label
        MultipleStartingPointParametersPanel_Rnd  matlab.ui.container.Panel
        SigmaMaxEditField             matlab.ui.control.NumericEditField
        LinearlyincreasesigmawitheachcaseuptosigmaLabel  matlab.ui.control.Label
        IncreaseSigmaCheckBox         matlab.ui.control.CheckBox
        MultiplicityButtonGroup_Rnd   matlab.ui.container.ButtonGroup
        MultipleButton_Rnd            matlab.ui.control.RadioButton
        SingleButton_Rnd              matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('temp_params.mat', 'params')
            
            %load in values from current save state
            app.MaxYawAngleEditField.Value = 45;
            app.YawSingleEditField.Value = 0;
            app.YawsfromEditField.Value = 0;
            app.toEditField.Value = 0;
            app.muEditField.Value = params.globcon.mu(1);
            app.sigmaEditField.Value = params.globcon.sigma(1);
            app.SigmaMaxEditField.Value = params.globcon.sigma_max(1);
            app.turbspac_EditField.Value = 560;

            if params.globcon.rnd_n > 1
                app.case_nEditField.Value = params.globcon.rnd_n;
            end
            
            if params.globcon.eql_n > 1
                app.case_nEditField.Value = params.globcon.eql_n;
            end

            nstep = app.case_nEditField.Value-1;
            
            if app.nmax_EditField.Value ~= (app.nmin_EditField.Value + nstep)
                app.nmax_EditField.Value = app.nmin_EditField.Value  + nstep;
            end
                        
            %disable all options initially, so user has to pick
            app.AllStartingYawsEqualPanel.Enable = "off";
            app.NormallyDistributedStartingYawsPanel.Enable = "off";
            app.MultipleStartingPointParametersPanel_Eql.Enable = "off";
            app.MultipleStartingPointParametersPanel_Rnd.Enable = "off";
            app.SigmaMaxEditField.Enable = "off";
            app.farmsz_Panel.Enable = "off";
            app.wdsweep_Panel.Enable = "off";
        end

        % Button pushed function: SaveandReturnButton
        function SaveandReturnButtonPushed(app, event)
            load('temp_params.mat', 'params');
            turbine_centres = params.farm.turbine_centres;

            %set the counter for random and equal cases to zero
            eql_n = 0;
            rnd_n = 0;
            wdsweep_n = 0;
            farmsz_n = 0;
            
            %if the user has selected the equal yaws panel 
            if app.AllStartingYawsEqualPanel.Enable == "on"
                  
                %if the user has selected the option of multiple cases
                if app.MultiplicityButtonGroup_Eql.SelectedObject ...
                                                 == app.MultipleButton_Eql
                    
                    %store number of cases
                    eql_n = app.case_nEditField.Value;
                    
                    %generate a vector containing the starting yaws of each
                    %case
                    eql_start_yaws = linspace(app.YawsfromEditField.Value,...
                                              app.toEditField.Value, ...
                                              eql_n);

                    params.globcon.yaw_init = eql_start_yaws;                    
                
                %if the user has selected the option of a single case    
                elseif app.MultiplicityButtonGroup_Eql.SelectedObject ...
                                                 == app.SingleButton_Eql
                    eql_n = 1;
                    params.globcon.yaw_init = app.YawSingleEditField.Value;
                end
            end
            

            if app.NormallyDistributedStartingYawsPanel.Enable == "on"
 
                mu = app.muEditField.Value;
                sigma = app.sigmaEditField.Value;

                rnd_n = 1;
                sigma_max = sigma;                

                if app.MultiplicityButtonGroup_Rnd.SelectedObject ...
                                                 == app.MultipleButton_Rnd

                    rnd_n = app.case_nEditField.Value;
                    
                    if app.SigmaMaxEditField.Enable == "on"

                        sigma_max = app.SigmaMaxEditField.Value;

                    end

                end


            params.globcon.yaw_init = ones(1,rnd_n).*mu;
            params.globcon.mu = mu;
            params.globcon.sigma = sigma;
            params.globcon.sigma_max = sigma_max;                             
            end


        if app.farmsz_Panel.Enable == "on"
            nmin = app.nmin_EditField.Value;
            nmax = app.nmax_EditField.Value;
            min_sep = app.turbspac_EditField.Value;
            farmsz_n = app.case_nEditField.Value;

            
            for i = nmin:nmax
            coords = linspace(0,(i-1)*min_sep,i);
            
            [xgrid, ygrid] = meshgrid(coords);
            turbine_centres = zeros(numel(xgrid),3);

            turbine_centres(:,1) = reshape(xgrid,numel(xgrid),1);
            turbine_centres(:,2) = reshape(ygrid,numel(ygrid),1);
            turbine_centres(:,3) = zeros;

            turb_grid_array{i-nmin+1} = turbine_centres;
            end


            turbine_centres = turb_grid_array;
        else
            turbine_centres = params.farm.turbine_centres;
        
        
        end


        if app.wdsweep_Panel.Enable == "on"
            maxwd = app.maxwd_EditField.Value;
            minwd = app.minwd_EditField.Value;
            wdsweep_n = app.case_nEditField.Value;
            

            wd_range = linspace(minwd, maxwd, wdsweep_n);

            params.globcon.wd_range = wd_range;
           

        end


        params.globcon.turbine_centres = turbine_centres;
        params.globcon.ub = app.MaxYawAngleEditField.Value;
        params.globcon.lb = -app.MaxYawAngleEditField.Value;
        params.globcon.eql_n = eql_n;
        params.globcon.rnd_n = rnd_n;
        params.globcon.wdsweep_n = wdsweep_n;
        params.globcon.farmsz_n = farmsz_n;
        
        save('temp_params.mat', 'params');
        delete(app);
        end

        % Value changed function: CheckBox_EqualYaws
        function CheckBox_EqualYawsValueChanged(app, event)
            % disable inputs for equal yaw values unless the checkbox is
            % ticked
            
            value = app.CheckBox_EqualYaws.Value;
             
            if value == 1
                app.AllStartingYawsEqualPanel.Enable = "on";
                app.NormallyDistributedStartingYawsPanel.Enable = "off";
                app.CheckBox_RandomYaws.Value = 0;

            else
                app.AllStartingYawsEqualPanel.Enable = "off";

            end
        end

        % Value changed function: CheckBox_RandomYaws
        function CheckBox_RandomYawsValueChanged(app, event)
            % disable inputs for random yaw values unless the checkbox is
            % ticked
            
            value = app.CheckBox_RandomYaws.Value;
            
            if value == 1
                app.NormallyDistributedStartingYawsPanel.Enable = "on";
                app.AllStartingYawsEqualPanel.Enable = "off";
                app.CheckBox_EqualYaws.Value = 0;

            else
                app.NormallyDistributedStartingYawsPanel.Enable = "off";

            end
        end

        % Selection changed function: MultiplicityButtonGroup_Eql
        function MultiplicityButtonGroup_EqlSelectionChanged(app, event)
            %depending on radio button selection enable panels for either
            %single or multiple case inputs
            
            selectedButton = app.MultiplicityButtonGroup_Eql.SelectedObject;
            
            if selectedButton == app.MultipleButton_Eql

                app.MultipleStartingPointParametersPanel_Eql.Enable = "on";
                app.SingleStartPointParametersPanel_Eql.Enable = "off";
            
            else

                app.MultipleStartingPointParametersPanel_Eql.Enable = "off";
                app.SingleStartPointParametersPanel_Eql.Enable = "on";
                app.wdsweep_Panel.Enable = "off";
                app.farmsz_Panel.Enable = "off";
                app.windsweep_CheckBox.Value = 0;
                app.farmsz_CheckBox.Value = 0;
            end
        end

        % Selection changed function: MultiplicityButtonGroup_Rnd
        function MultiplicityButtonGroup_RndSelectionChanged(app, event)
            %depending on radio button selection enable panels for either
            %single or multiple case inputs

            selectedButton = app.MultiplicityButtonGroup_Rnd.SelectedObject;
            
            if selectedButton == app.MultipleButton_Rnd

                app.MultipleStartingPointParametersPanel_Rnd.Enable = "on";
                app.RandomGenerationParametersPanel_Rnd.Enable = "on";
            
            else

                app.MultipleStartingPointParametersPanel_Rnd.Enable = "off";
                app.RandomGenerationParametersPanel_Rnd.Enable = "on";
                app.wdsweep_Panel.Enable = "off";
                app.farmsz_Panel.Enable = "off";
                app.windsweep_CheckBox.Value = 0;
                app.farmsz_CheckBox.Value = 0;
            end
        end

        % Value changed function: SigmaMaxEditField
        function SigmaMaxEditFieldValueChanged(app, event)
            value = app.SigmaMaxEditField.Value;
            
            if value < app.sigmaEditField.Value
                app.SigmaMaxEditField.Value = app.sigmaEditField.Value;
            end

        end

        % Value changed function: IncreaseSigmaCheckBox
        function IncreaseSigmaCheckBoxValueChanged(app, event)
            value = app.IncreaseSigmaCheckBox.Value;                    

            if value == 1
                app.SigmaMaxEditField.Enable = "on";
            else
                app.SigmaMaxEditField.Enable = "off";
            end
        end

        % Value changed function: YawsfromEditField
        function YawsfromEditFieldValueChanged(app, event)
            value = app.YawsfromEditField.Value;
            
            if value < -app.MaxYawAngleEditField.Value
                app.YawsfromEditField.Value = -app.MaxYawAngleEditField.Value;
            end
        end

        % Value changed function: toEditField
        function toEditFieldValueChanged(app, event)
            value = app.toEditField.Value;
            
            if value > app.MaxYawAngleEditField.Value
                app.YawsfromEditField.Value = app.MaxYawAngleEditField.Value;
            end
        end

        % Value changed function: nmax_EditField
        function nmax_EditFieldValueChanged(app, event)
            nstep = app.case_nEditField.Value-1;
            
            if app.nmax_EditField.Value ~= (app.nmin_EditField.Value + nstep)
                app.nmax_EditField.Value = app.nmin_EditField.Value  + nstep;
            end

        end

        % Value changed function: nmin_EditField
        function nmin_EditFieldValueChanged(app, event)
            nstep = app.case_nEditField.Value-1;
            
            if app.nmax_EditField.Value ~= (app.nmin_EditField.Value + nstep)
                app.nmax_EditField.Value = app.nmin_EditField.Value + nstep;
            end
        end

        % Value changed function: case_nEditField
        function case_nEditFieldValueChanged(app, event)
            nstep = app.case_nEditField.Value-1;
            
            if app.nmax_EditField.Value ~= (app.nmin_EditField.Value + nstep)
                app.nmax_EditField.Value = app.nmin_EditField.Value  + nstep;
            end
            
        end

        % Value changed function: windsweep_CheckBox
        function windsweep_CheckBoxValueChanged(app, event)
            value = app.windsweep_CheckBox.Value;
            
            if value == 1
                app.wdsweep_Panel.Enable = "on";
                app.MultipleStartingPointParametersPanel_Rnd.Enable = "on";
                app.RandomGenerationParametersPanel_Rnd.Enable = "on";
                app.MultiplicityButtonGroup_Rnd.SelectedObject = app.MultipleButton_Rnd;

                app.MultipleStartingPointParametersPanel_Eql.Enable = "on";
                app.SingleStartPointParametersPanel_Eql.Enable = "off";
                app.MultiplicityButtonGroup_Eql.SelectedObject = app.MultipleButton_Eql;
            
            else
                app.wdsweep_Panel.Enable  = "off";
            end
        end

        % Value changed function: farmsz_CheckBox
        function farmsz_CheckBoxValueChanged(app, event)
            value = app.farmsz_CheckBox.Value;
            
            if value == 1
                app.farmsz_Panel.Enable = "on";
                app.MultipleStartingPointParametersPanel_Rnd.Enable = "on";
                app.RandomGenerationParametersPanel_Rnd.Enable = "on";
                app.MultiplicityButtonGroup_Rnd.SelectedObject = app.MultipleButton_Rnd;

                app.MultipleStartingPointParametersPanel_Eql.Enable = "on";
                app.SingleStartPointParametersPanel_Eql.Enable = "off";
                app.MultiplicityButtonGroup_Eql.SelectedObject = app.MultipleButton_Eql;
            
            else
                app.farmsz_Panel.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NormallyDistributedStartingYawsPanel = uipanel(app.UIFigure);
            app.NormallyDistributedStartingYawsPanel.Title = 'Normally Distributed Starting Yaws';
            app.MultiplicityButtonGroup_Rnd = uibuttongroup(app.NormallyDistributedStartingYawsPanel);
            app.MultiplicityButtonGroup_Rnd.SelectionChangedFcn = createCallbackFcn(app, @MultiplicityButtonGroup_RndSelectionChanged, true);
            app.MultiplicityButtonGroup_Rnd.Title = 'Multiplicity of Start Points to Evaluate';
            app.SingleButton_Rnd = uiradiobutton(app.MultiplicityButtonGroup_Rnd);
            app.SingleButton_Rnd.Text = 'Single';
            app.SingleButton_Rnd.Value = true;
            app.MultipleButton_Rnd = uiradiobutton(app.MultiplicityButtonGroup_Rnd);
            app.MultipleButton_Rnd.Text = 'Multiple';
            app.MultipleStartingPointParametersPanel_Rnd = uipanel(app.NormallyDistributedStartingYawsPanel);
            app.MultipleStartingPointParametersPanel_Rnd.Title = 'Multiple Starting Point Parameters';
            app.IncreaseSigmaCheckBox = uicheckbox(app.MultipleStartingPointParametersPanel_Rnd);
            app.IncreaseSigmaCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncreaseSigmaCheckBoxValueChanged, true);
            app.IncreaseSigmaCheckBox.Text = '';
            app.LinearlyincreasesigmawitheachcaseuptosigmaLabel = uilabel(app.MultipleStartingPointParametersPanel_Rnd);
            app.LinearlyincreasesigmawitheachcaseuptosigmaLabel.Interpreter = 'latex';
            app.LinearlyincreasesigmawitheachcaseuptosigmaLabel.Text = {'Linearly increase \(\sigma\) with'; 'each case up to \(\sigma = \)'};
            app.SigmaMaxEditField = uieditfield(app.MultipleStartingPointParametersPanel_Rnd, 'numeric');
            app.SigmaMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaMaxEditFieldValueChanged, true);
            app.SigmaMaxEditField.Value = 45;
            app.RandomGenerationParametersPanel_Rnd = uipanel(app.NormallyDistributedStartingYawsPanel);
            app.RandomGenerationParametersPanel_Rnd.Title = 'Random Generation Parameters';
            app.muLabel = uilabel(app.RandomGenerationParametersPanel_Rnd);
            app.muLabel.Interpreter = 'latex';
            app.muLabel.Text = '\(\mu =\)';
            app.muEditField = uieditfield(app.RandomGenerationParametersPanel_Rnd, 'numeric');
            app.sigmaEditFieldLabel = uilabel(app.RandomGenerationParametersPanel_Rnd);
            app.sigmaEditFieldLabel.Interpreter = 'latex';
            app.sigmaEditFieldLabel.Text = '\(\sigma =\)';
            app.sigmaEditField = uieditfield(app.RandomGenerationParametersPanel_Rnd, 'numeric');
            app.sigmaEditField.Value = 15;
            app.AllStartingYawsEqualPanel = uipanel(app.UIFigure);
            app.AllStartingYawsEqualPanel.Title = 'All Starting Yaws Equal';
            app.MultiplicityButtonGroup_Eql = uibuttongroup(app.AllStartingYawsEqualPanel);
            app.MultiplicityButtonGroup_Eql.SelectionChangedFcn = createCallbackFcn(app, @MultiplicityButtonGroup_EqlSelectionChanged, true);
            app.MultiplicityButtonGroup_Eql.Title = 'Multiplicity of Start Points to Evaluate';
            app.SingleButton_Eql = uiradiobutton(app.MultiplicityButtonGroup_Eql);
            app.SingleButton_Eql.Text = 'Single';
            app.SingleButton_Eql.Value = true;
            app.MultipleButton_Eql = uiradiobutton(app.MultiplicityButtonGroup_Eql);
            app.MultipleButton_Eql.Text = 'Multiple';
            app.SingleStartPointParametersPanel_Eql = uipanel(app.AllStartingYawsEqualPanel);
            app.SingleStartPointParametersPanel_Eql.Title = 'Single Start Point Parameters';
            app.YawdegreesEditFieldLabel = uilabel(app.SingleStartPointParametersPanel_Eql);
            app.YawdegreesEditFieldLabel.Text = 'Yaw (degrees)';
            app.YawSingleEditField = uieditfield(app.SingleStartPointParametersPanel_Eql, 'numeric');
            app.YawSingleEditField.Limits = [-180 180];
            app.MultipleStartingPointParametersPanel_Eql = uipanel(app.AllStartingYawsEqualPanel);
            app.MultipleStartingPointParametersPanel_Eql.Title = 'Multiple Starting Point Parameters';
            app.YawsfromEditFieldLabel = uilabel(app.MultipleStartingPointParametersPanel_Eql);
            app.YawsfromEditFieldLabel.Text = 'Yaws from';
            app.YawsfromEditField = uieditfield(app.MultipleStartingPointParametersPanel_Eql, 'numeric');
            app.YawsfromEditField.Limits = [-180 179];
            app.YawsfromEditField.ValueChangedFcn = createCallbackFcn(app, @YawsfromEditFieldValueChanged, true);
            app.YawsfromEditField.Value = -45;
            app.toEditFieldLabel = uilabel(app.MultipleStartingPointParametersPanel_Eql);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField = uieditfield(app.MultipleStartingPointParametersPanel_Eql, 'numeric');
            app.toEditField.Limits = [-179 180];
            app.toEditField.ValueChangedFcn = createCallbackFcn(app, @toEditFieldValueChanged, true);
            app.toEditField.Value = 45;
            app.wdsweep_Panel = uipanel(app.UIFigure);
            app.wdsweep_Panel.Title = 'Sweep Wind Direction Over Cases';
            app.SweepincomingwinddirectionforthefarmfromLabel = uilabel(app.wdsweep_Panel);
            app.SweepincomingwinddirectionforthefarmfromLabel.Text = {'Sweep incoming wind '; 'direction for the farm from'};
            app.minwd_EditField = uieditfield(app.wdsweep_Panel, 'numeric');
            app.minwd_EditField.Limits = [0 359];
            app.toEditField_2Label = uilabel(app.wdsweep_Panel);
            app.toEditField_2Label.Text = 'to';
            app.maxwd_EditField = uieditfield(app.wdsweep_Panel, 'numeric');
            app.maxwd_EditField.Limits = [1 360];
            app.maxwd_EditField.Value = 360;
            app.circLabel_2 = uilabel(app.wdsweep_Panel);
            app.circLabel_2.Interpreter = 'latex';
            app.circLabel_2.Text = '\(^{\circ}\)';
            app.circLabel = uilabel(app.wdsweep_Panel);
            app.circLabel.Interpreter = 'latex';
            app.circLabel.Text = '\(^{\circ}\)';
            app.farmsz_Panel = uipanel(app.UIFigure);
            app.farmsz_Panel.Title = 'Increase farm size over cases';
            app.ForanNxNgridwhereNrepresentsthenumberofturbinesLabel = uilabel(app.farmsz_Panel);
            app.ForanNxNgridwhereNrepresentsthenumberofturbinesLabel.Text = {'For an NxN square grid where N represents the '; 'number of turbines per dimension:'};
            app.IncreaseNfromEditFieldLabel = uilabel(app.farmsz_Panel);
            app.IncreaseNfromEditFieldLabel.Text = 'Increase N from';
            app.nmin_EditField = uieditfield(app.farmsz_Panel, 'numeric');
            app.nmin_EditField.Limits = [1 Inf];
            app.nmin_EditField.ValueChangedFcn = createCallbackFcn(app, @nmin_EditFieldValueChanged, true);
            app.nmin_EditField.Value = 1;
            app.toEditField_2Label_2 = uilabel(app.farmsz_Panel);
            app.toEditField_2Label_2.Text = 'to';
            app.nmax_EditField = uieditfield(app.farmsz_Panel, 'numeric');
            app.nmax_EditField.Limits = [1 Inf];
            app.nmax_EditField.RoundFractionalValues = 'on';
            app.nmax_EditField.ValueChangedFcn = createCallbackFcn(app, @nmax_EditFieldValueChanged, true);
            app.nmax_EditField.Editable = 'off';
            app.nmax_EditField.Value = 10;
            app.withaspacingofEditFieldLabel = uilabel(app.farmsz_Panel);
            app.withaspacingofEditFieldLabel.Text = 'with a spacing of ';
            app.turbspac_EditField = uieditfield(app.farmsz_Panel, 'numeric');
            app.turbspac_EditField.Value = 560;
            app.mbetweenturbinesLabel = uilabel(app.farmsz_Panel);
            app.mbetweenturbinesLabel.Text = 'm between turbines';
            app.NOTEEnablingthiswilldisregardthelayoutsetbytheuserLabel = uilabel(app.farmsz_Panel);
            app.NOTEEnablingthiswilldisregardthelayoutsetbytheuserLabel.Text = {'NOTE: Change the number of start points to '; 'change the range of N. Enabling this panel will'; 'disregard the layout set by the user'};
            app.ChooseoptimisationconstraintsandFarmStatestoevaluateLabel = uilabel(app.UIFigure);
            app.ChooseoptimisationconstraintsandFarmStatestoevaluateLabel.Text = 'Choose optimisation constraints and Farm State(s) to evaluate';
            app.MaximumYawLabel = uilabel(app.UIFigure);
            app.MaximumYawLabel.Interpreter = 'latex';
            app.MaximumYawLabel.Text = 'Magnitude of Maximum Yaw Angle (\(\degree\))';
            app.MaxYawAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxYawAngleEditField.Limits = [0 180];
            app.MaxYawAngleEditField.Value = 45;
            app.CheckBox_EqualYaws = uicheckbox(app.UIFigure);
            app.CheckBox_EqualYaws.ValueChangedFcn = createCallbackFcn(app, @CheckBox_EqualYawsValueChanged, true);
            app.CheckBox_EqualYaws.Text = '';
            app.windsweep_CheckBox = uicheckbox(app.UIFigure);
            app.windsweep_CheckBox.ValueChangedFcn = createCallbackFcn(app, @windsweep_CheckBoxValueChanged, true);
            app.windsweep_CheckBox.Text = '';
            app.CheckBox_RandomYaws = uicheckbox(app.UIFigure);
            app.CheckBox_RandomYaws.ValueChangedFcn = createCallbackFcn(app, @CheckBox_RandomYawsValueChanged, true);
            app.CheckBox_RandomYaws.Text = '';
            app.SaveandReturnButton = uibutton(app.UIFigure, 'push');
            app.SaveandReturnButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandReturnButtonPushed, true);
            app.SaveandReturnButton.Text = 'Save and Return';
            app.NumberofstartpointstoevaluateLabel = uilabel(app.UIFigure);
            app.NumberofstartpointstoevaluateLabel.Text = {'Number of start points to evaluate'; '(the range specified in the multiplicity options '; 'will be divided into this many equal steps)'};
            app.case_nEditField = uieditfield(app.UIFigure, 'numeric');
            app.case_nEditField.Limits = [2 Inf];
            app.case_nEditField.RoundFractionalValues = 'on';
            app.case_nEditField.ValueChangedFcn = createCallbackFcn(app, @case_nEditFieldValueChanged, true);
            app.case_nEditField.Value = 2;
            app.farmsz_CheckBox = uicheckbox(app.UIFigure);
            app.farmsz_CheckBox.ValueChangedFcn = createCallbackFcn(app, @farmsz_CheckBoxValueChanged, true);
            app.farmsz_CheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_inityaw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end