classdef AWFOM_locgen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GenerateRandomTurbineLocationsButton  matlab.ui.control.Button
        MinimumseparationmEditField     matlab.ui.control.NumericEditField
        MinimumseparationmEditFieldLabel  matlab.ui.control.Label
        GenerationConstraintsLabel      matlab.ui.control.Label
        NumberofturbinesEditField       matlab.ui.control.NumericEditField
        NumberofturbinesEditFieldLabel  matlab.ui.control.Label
        FarmDimensionsLabel             matlab.ui.control.Label
        LatitudinalkmEditField          matlab.ui.control.NumericEditField
        LatitudinalkmEditFieldLabel     matlab.ui.control.Label
        LongitudinalkmEditField         matlab.ui.control.NumericEditField
        LongitudinalkmEditFieldLabel    matlab.ui.control.Label
    end

    
    methods (Access = private)
        %Function to write locations from an incompatible parameters list
        function uselocs(app,~,event, turbine_centres, n_turb, farm_size, min_separation)
            if event.SelectedOption == "Use Locations"
                load('temp_params.mat',"params"); 
                params.farm.turbine_centres = turbine_centres;
                params.farm.n_turb = n_turb;                
                params.farm.farm_size = farm_size;
                params.farm.min_separation = min_separation;
                save('temp_params.mat',"params"); 
                delete(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('temp_params.mat','params');
            app.LongitudinalkmEditField.Value = params.farm.farm_size(1)/1000;
            app.LatitudinalkmEditField.Value = params.farm.farm_size(1)/1000;
            app.NumberofturbinesEditField.Value = params.farm.n_turb;
            app.MinimumseparationmEditField.Value = params.farm.min_separation;

        end

        % Button pushed function: GenerateRandomTurbineLocationsButton
        function GenerateRandomTurbineLocationsButtonPushed(app, event)
            load('temp_params.mat',"params"); 
            
            %randomly generate turbine locations with given parameters
            x_length = round(1000*app.LongitudinalkmEditField.Value);
            y_length = round(1000*app.LatitudinalkmEditField.Value);
            
            farm_size = [x_length, y_length];
            min_separation = app.MinimumseparationmEditField.Value;

            locs = random_turbines(x_length,y_length, app.NumberofturbinesEditField.Value, app.MinimumseparationmEditField.Value);
            
            %check if the generation has been succesful
            n_turb = size(locs,1);            
                   
            
            %if generation unsuccesful inform user and suggest solution
            if n_turb < app.NumberofturbinesEditField.Value
            message = sprintf('%d turbines generated in %d tries. These locations will be used unless the generator is run again. Try either reducing the number of turbines or increasing the farm size', n_turb, 5000*app.NumberofturbinesEditField.Value);
            title = sprintf('Incompatible Parameters');
            %option to change paramaters, or accept current location file
            uiconfirm(app.UIFigure, message, title, 'Options',{'Use Locations','Return to parameters window'}, 'DefaultOption',1,'CloseFcn',{@app.uselocs, locs, n_turb, farm_size, min_separation})
                
            
            else
                params.farm.turbine_centres = locs;
                params.farm.n_turb = n_turb;
                params.farm.farm_size = farm_size;
                params.farm.min_separation = min_separation;
                save('temp_params.mat',"params");
                delete(app)
            end 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LongitudinalkmEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudinalkmEditFieldLabel.Text = 'Longitudinal (km)';
            app.LongitudinalkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudinalkmEditField.Limits = [0 Inf];
            app.LongitudinalkmEditField.Value = 2;
            app.LatitudinalkmEditFieldLabel = uilabel(app.UIFigure);
            app.LatitudinalkmEditFieldLabel.Text = 'Latitudinal (km)';
            app.LatitudinalkmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudinalkmEditField.Limits = [0 Inf];
            app.LatitudinalkmEditField.Value = 2;
            app.FarmDimensionsLabel = uilabel(app.UIFigure);
            app.FarmDimensionsLabel.Text = 'Farm Dimensions';
            app.NumberofturbinesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofturbinesEditFieldLabel.Text = 'Number of turbines';
            app.NumberofturbinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofturbinesEditField.Limits = [0 Inf];
            app.NumberofturbinesEditField.RoundFractionalValues = 'on';
            app.NumberofturbinesEditField.Value = 40;
            app.GenerationConstraintsLabel = uilabel(app.UIFigure);
            app.GenerationConstraintsLabel.Text = 'Generation Constraints';
            app.MinimumseparationmEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumseparationmEditFieldLabel.Text = 'Minimum separation (m)';
            app.MinimumseparationmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumseparationmEditField.Limits = [0 Inf];
            app.MinimumseparationmEditField.Value = 320;
            app.GenerateRandomTurbineLocationsButton = uibutton(app.UIFigure, 'push');
            app.GenerateRandomTurbineLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRandomTurbineLocationsButtonPushed, true);
            app.GenerateRandomTurbineLocationsButton.Text = 'Generate Random Turbine Locations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_locgen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end