classdef AWFOM_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FlowFieldVisualisationPanel    matlab.ui.container.Panel
        SetResolutionofFlowFieldLabel  matlab.ui.control.Label
        ffresEditField                 matlab.ui.control.NumericEditField
        ffPlotTurbinesandEvaluateFlowFieldButton  matlab.ui.control.Button
        ChooseFlowFieldAPanel          matlab.ui.container.Panel
        ffStartCaseDropDown_A          matlab.ui.control.DropDown
        StartCaseDropDownLabel         matlab.ui.control.Label
        ffFarmStateDropDown_A          matlab.ui.control.DropDown
        FarmStateDropDownLabel         matlab.ui.control.Label
        ffSolverDropDown_A             matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        ChooseFlowFieldBPanel          matlab.ui.container.Panel
        ffStartCaseDropDown_B          matlab.ui.control.DropDown
        StartCaseDropDownLabel_2       matlab.ui.control.Label
        ffFarmStateDropDown_B          matlab.ui.control.DropDown
        FarmStateDropDownLabel_2       matlab.ui.control.Label
        ffSolverDropDown_B             matlab.ui.control.DropDown
        SolverDropDownLabel_2          matlab.ui.control.Label
        ffUIAxes_A                     matlab.ui.control.UIAxes
        ffUIAxes_B                     matlab.ui.control.UIAxes
        FarmPerformancePanel           matlab.ui.container.Panel
        fpYValueDropDown               matlab.ui.control.DropDown
        rvalueLabel                    matlab.ui.control.Label
        fpCaseDropDown                 matlab.ui.control.DropDown
        CaseDropDownLabel              matlab.ui.control.Label
        fpSolverDropDown               matlab.ui.control.DropDown
        PlotSolverLabel                matlab.ui.control.Label
        fpUIAxes                       matlab.ui.control.UIAxes
        OptimisationPerformancePanel   matlab.ui.container.Panel
        opCaseDropDown                 matlab.ui.control.DropDown
        CaseDropDownLabel_2            matlab.ui.control.Label
        opSolverDropDown               matlab.ui.control.DropDown
        PlotSolverLabel_2              matlab.ui.control.Label
        opDisplayplotofDropDown        matlab.ui.control.DropDown
        DisplayplotofDropDownLabel     matlab.ui.control.Label
        opUIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
      
        %% FUNCTION TO PLOT TURBINE PERFORMANCE VALUES
        function plotfarmperformance(app)
            load("formatted_results.mat", "axesData")
            solver = app.fpSolverDropDown.Value;
            case_n = app.fpCaseDropDown.Value;
            r_value = str2double(app.fpYValueDropDown.Value);
            y_label = find(strcmp(app.fpYValueDropDown.ItemsData,app.fpYValueDropDown.Value));


            if solver == 1+size(axesData.solverNames,1)
                solver = 1:size(axesData.solverNames,1);
            end

            if case_n == 1+size(axesData.caseNames,1)
                case_n = 1:size(axesData.caseNames,1);
            end            

            Y = axesData.dataArray(solver,case_n,r_value);           
            
            plotsz = size(Y);       
            
            %create legend labels
            leg_sol = axesData.solverNames(solver);
            leg_case = case_n;
            lc = 1;
            leg_length = numel(leg_sol)*numel(leg_case);            
            leg = cell(1,leg_length);   


            cla(app.fpUIAxes)
            xlabel(app.fpUIAxes, {'Turbine ID'})
            ylabel(app.fpUIAxes, app.fpYValueDropDown.Items(y_label))
                        
            %create counter for legend creation
            
            for i = 1:plotsz(1)
                for j = 1:plotsz(2)
                    y_plot = cell2mat(Y(i,j));
                    n_turb = size(y_plot,1);
                    plot(app.fpUIAxes,(1:n_turb), y_plot);
                    hold(app.fpUIAxes, "on")
                    leg{1,lc} = strcat('Case',{' '},string(leg_case(j)),{' '},leg_sol{i});
                    lc = lc+1;
                end
            end

            legend(app.fpUIAxes, string(leg))
            axis(app.fpUIAxes, "padded")
        end


        %% FUNCTION TO PLOT OPTIMISATION PERFORMANCE
        function plotoptimperformance(app)
            load("formatted_results.mat", "axesData");
            plot_type = str2double(app.opDisplayplotofDropDown.Value); 

            solver = app.opSolverDropDown.Value;
            case_n = app.opCaseDropDown.Value;
            
                if solver == 1+size(axesData.solverNames,1)
                    solver = 1:size(axesData.solverNames,1);
                end
    
                if case_n == 1+size(axesData.caseNames,1)
                    case_n = 1:size(axesData.caseNames,1);
                end


            ylabel(app.opUIAxes, app.opDisplayplotofDropDown.ItemsData(plot_type));            
            xlabel(app.opUIAxes, 'Case Names')            
            
                if plot_type == 1
                    cla(app.opUIAxes)
                    Y = axesData.dataArray(solver,case_n,7);
                    Y = cell2mat(Y');
                    bar(app.opUIAxes, categorical(axesData.caseNames(case_n)), Y)
                    ylabel(app.opUIAxes, 'Solver Iterations')
    
                elseif plot_type == 2
                    cla(app.opUIAxes)
                    Y = axesData.dataArray(solver,case_n,11);
                    Y = cell2mat(Y');
                    bar(app.opUIAxes, categorical(axesData.caseNames(case_n)), Y)
                    ylabel(app.opUIAxes, 'Function Evaluations')
    
                elseif plot_type == 3
                    cla(app.opUIAxes)                
                    farm_power = axesData.dataArray(solver,case_n,14);
                    it_no = axesData.dataArray(solver,case_n,13);
                    plotsz = size(farm_power); 
    
                    for i = 1:plotsz(1)
                        for j = 1:plotsz(2)
                            plot(app.opUIAxes,cell2mat(it_no(i,j)), cell2mat(farm_power(i,j)));
                            hold(app.opUIAxes, "on")
                        end
                    end
    
                    ylabel(app.opUIAxes, 'Farm Power (MW)')
                    xlabel(app.opUIAxes, 'Solver Iterations')
                
                end 
                axis(app.opUIAxes, "padded")
        end
    
        %% FUNCTION TO PLOT FLOW FIELD
        function ffplot(app, ax, ff_res, solver, r_case, farm_state)
            load("params.mat",'params');
            load("formatted_results.mat", "axesData");
            
            %read and store the resolution of the flow diagram as an
            %integer value
            ff_res = round(ff_res);
            
            %store the selected farm state
            yaw_current = cell2mat(axesData.dataArray((solver),(r_case),(farm_state)));
            turbine_centres = cell2mat(axesData.dataArray((solver),(r_case),17));
            wind_direction = cell2mat(axesData.dataArray((solver),(r_case),16));
            diameters = cell2mat(axesData.dataArray((solver),(r_case),18));
            n_turb = size(yaw_current,1);
            farm_size(1) = min(turbine_centres(:,1))-500;
            farm_size(2) = min(turbine_centres(:,2))-500;
            farm_size(3) = max(turbine_centres(:,1))+500;
            farm_size(4) = max(turbine_centres(:,2))+500;


            %evaluate the flow field
            save(".\params.mat",'params');

            ws_arry = flow_field_eval(ff_res, farm_size, wind_direction, ...
                                        turbine_centres, yaw_current, diameters);
            %read in the size of the farm to start normalising turbine
            %locations for plotting
            
            flow_field = ws_arry;
            
            cla(ax);
            hold(ax,'on');
            axis(ax, 'equal');
            image(ax,[farm_size(1), farm_size(3)],[farm_size(2), farm_size(4)],flow_field, 'CDataMapping', 'scaled');
            colormap(ax, flipud(turbo));

            turbine_faces = plot_turbines(yaw_current,...
                                          n_turb,...
                                          wind_direction,...
                                          diameters,...
                                          turbine_centres);
            

            tipx = turbine_faces(:,1:2);
            tipy = turbine_faces(:,3:4);           
            
            %calculate max dim of farm size for turbine line scaling
            xlength = farm_size(3) - farm_size(1);
            ylength = farm_size(4) - farm_size(2);
            maxdim = max(xlength,ylength);

            for i = 1:size(tipx,1)
            p = plot(ax,tipx(i,:), tipy(i,:));
            p.Color = [1,0,1];
            p.LineWidth = 0.00045*maxdim;
            end
            
            c = colorbar(ax);
            c.Label.String = 'Wind Speed in m/s';
            xlim(ax, [farm_size(1), farm_size(3)])
            ylim(ax, [farm_size(2), farm_size(4)])
            hold(ax,'off');
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %read in params and results file and set the limits for the
            %case selection slider
            load("params.mat", "params");
            format_results("params.mat");
            load("formatted_results.mat", "axesData");

            %initialise values for optimisation performance diagram
            app.opSolverDropDown.Items = [axesData.solverNames ; {'All Solvers'}];
            app.opSolverDropDown.ItemsData = (1:1+size(axesData.solverNames,1));
            app.opCaseDropDown.Items = [axesData.caseNames; {'All Cases'}];
            app.opCaseDropDown.ItemsData = (1:1+size(axesData.caseNames,1));
            

            %initialise values for farm performance diagram
            app.fpSolverDropDown.Items = [axesData.solverNames ; {'All Solvers'}];
            app.fpSolverDropDown.ItemsData = (1:1+size(axesData.solverNames,1));
            app.fpCaseDropDown.Items = [axesData.caseNames; {'All Cases'}];
            app.fpCaseDropDown.ItemsData = (1:1+size(axesData.caseNames,1));

            %initialise values for flow field diagram
            app.ffSolverDropDown_A.Items = axesData.solverNames;
            app.ffSolverDropDown_A.ItemsData = (1:size(axesData.solverNames,1));
            app.ffStartCaseDropDown_A.Items = axesData.caseNames;
            app.ffStartCaseDropDown_A.ItemsData = (1:size(axesData.caseNames,1));
            
            app.ffSolverDropDown_B.Items = axesData.solverNames;
            app.ffSolverDropDown_B.ItemsData = (1:size(axesData.solverNames,1));
            app.ffStartCaseDropDown_B.Items = axesData.caseNames;
            app.ffStartCaseDropDown_B.ItemsData = (1:size(axesData.caseNames,1));
           
            %plot all graphs with their default values
            plotoptimperformance(app)
            plotfarmperformance(app)

            ffplot(app, app.ffUIAxes_A, app.ffresEditField.Value, app.ffSolverDropDown_A.Value, ...
                   app.ffStartCaseDropDown_A.Value, app.ffFarmStateDropDown_A.Value);

            ffplot(app, app.ffUIAxes_B, app.ffresEditField.Value, app.ffSolverDropDown_B.Value, ...
                   app.ffStartCaseDropDown_B.Value, str2double(app.ffFarmStateDropDown_B.Value));

            drawnow;
            movegui(app.UIFigure,'center')
        end

        % Button pushed function: ffPlotTurbinesandEvaluateFlowFieldButton
        function ffPlotTurbinesandEvaluateFlowFieldButtonPushed(app, event)
            ffplot(app, app.ffUIAxes_A, app.ffresEditField.Value, app.ffSolverDropDown_A.Value, ...
                   app.ffStartCaseDropDown_A.Value, app.ffFarmStateDropDown_A.Value);

            ffplot(app, app.ffUIAxes_B, app.ffresEditField.Value, app.ffSolverDropDown_B.Value, ...
                   app.ffStartCaseDropDown_B.Value, str2double(app.ffFarmStateDropDown_B.Value));           
        end

        % Value changed function: fpSolverDropDown
        function fpSolverDropDownValueChanged(app, event)
            plotfarmperformance(app);        
        end

        % Value changed function: fpCaseDropDown
        function fpCaseDropDownValueChanged(app, event)
            plotfarmperformance(app);            
        end

        % Value changed function: fpYValueDropDown
        function fpYValueDropDownValueChanged(app, event)
            plotfarmperformance(app);            
        end

        % Value changed function: opDisplayplotofDropDown
        function opDisplayplotofDropDownValueChanged(app, event)
            plotoptimperformance(app)
        end

        % Value changed function: opSolverDropDown
        function opSolverDropDownValueChanged(app, event)
            plotoptimperformance(app)
        end

        % Value changed function: opCaseDropDown
        function opCaseDropDownValueChanged(app, event)
            plotoptimperformance(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptimisationPerformancePanel = uipanel(app.UIFigure);
            app.OptimisationPerformancePanel.Title = 'Optimisation Performance';
            app.opUIAxes = uiaxes(app.OptimisationPerformancePanel);
            title(app.opUIAxes, 'Title')
            xlabel(app.opUIAxes, 'X')
            ylabel(app.opUIAxes, 'Y')
            zlabel(app.opUIAxes, 'Z')
            app.DisplayplotofDropDownLabel = uilabel(app.OptimisationPerformancePanel);
            app.DisplayplotofDropDownLabel.Text = 'Display plot of:';
            app.opDisplayplotofDropDown = uidropdown(app.OptimisationPerformancePanel);
            app.opDisplayplotofDropDown.Items = {'Iterations/Solvers', 'Function Evaluations/Solvers', 'Farm Power/Iteration Count'};
            app.opDisplayplotofDropDown.ItemsData = {'1', '2', '3'};
            app.opDisplayplotofDropDown.ValueChangedFcn = createCallbackFcn(app, @opDisplayplotofDropDownValueChanged, true);
            app.opDisplayplotofDropDown.Value = '2';
            app.PlotSolverLabel_2 = uilabel(app.OptimisationPerformancePanel);
            app.PlotSolverLabel_2.Text = 'Solver';
            app.opSolverDropDown = uidropdown(app.OptimisationPerformancePanel);
            app.opSolverDropDown.ValueChangedFcn = createCallbackFcn(app, @opSolverDropDownValueChanged, true);
            app.CaseDropDownLabel_2 = uilabel(app.OptimisationPerformancePanel);
            app.CaseDropDownLabel_2.Text = 'Case';
            app.opCaseDropDown = uidropdown(app.OptimisationPerformancePanel);
            app.opCaseDropDown.ValueChangedFcn = createCallbackFcn(app, @opCaseDropDownValueChanged, true);
            app.FarmPerformancePanel = uipanel(app.UIFigure);
            app.FarmPerformancePanel.Title = 'Farm Performance';
            app.fpUIAxes = uiaxes(app.FarmPerformancePanel);
            title(app.fpUIAxes, 'Title')
            xlabel(app.fpUIAxes, 'X')
            ylabel(app.fpUIAxes, 'Y')
            zlabel(app.fpUIAxes, 'Z')
            app.PlotSolverLabel = uilabel(app.FarmPerformancePanel);
            app.PlotSolverLabel.Text = 'Solver';
            app.fpSolverDropDown = uidropdown(app.FarmPerformancePanel);
            app.fpSolverDropDown.ValueChangedFcn = createCallbackFcn(app, @fpSolverDropDownValueChanged, true);
            app.CaseDropDownLabel = uilabel(app.FarmPerformancePanel);
            app.CaseDropDownLabel.Text = 'Case';
            app.fpCaseDropDown = uidropdown(app.FarmPerformancePanel);
            app.fpCaseDropDown.ValueChangedFcn = createCallbackFcn(app, @fpCaseDropDownValueChanged, true);
            app.rvalueLabel = uilabel(app.FarmPerformancePanel);
            app.rvalueLabel.Text = 'Y-value';
            app.fpYValueDropDown = uidropdown(app.FarmPerformancePanel);
            app.fpYValueDropDown.Items = {'Initial Turbine Power (MW)', 'Optimised Turbine Power (MW)', 'Change in Turbine Power (MW)', 'Change in Yaw Angle (Degrees)'};
            app.fpYValueDropDown.ItemsData = {'8', '9', '10', '3'};
            app.fpYValueDropDown.ValueChangedFcn = createCallbackFcn(app, @fpYValueDropDownValueChanged, true);
            app.fpYValueDropDown.Value = '8';
            app.FlowFieldVisualisationPanel = uipanel(app.UIFigure);
            app.FlowFieldVisualisationPanel.Title = 'Flow Field Visualisation';
            app.ffUIAxes_B = uiaxes(app.FlowFieldVisualisationPanel);
            title(app.ffUIAxes_B, 'Flow Field B')
            xlabel(app.ffUIAxes_B, 'X')
            ylabel(app.ffUIAxes_B, 'Y')
            zlabel(app.ffUIAxes_B, 'Z')
            app.ffUIAxes_A = uiaxes(app.FlowFieldVisualisationPanel);
            title(app.ffUIAxes_A, 'Flow Field A')
            xlabel(app.ffUIAxes_A, 'X')
            ylabel(app.ffUIAxes_A, 'Y')
            zlabel(app.ffUIAxes_A, 'Z')
            app.ChooseFlowFieldBPanel = uipanel(app.FlowFieldVisualisationPanel);
            app.ChooseFlowFieldBPanel.Title = 'Choose Flow Field B';
            app.SolverDropDownLabel_2 = uilabel(app.ChooseFlowFieldBPanel);
            app.SolverDropDownLabel_2.Text = 'Solver';
            app.ffSolverDropDown_B = uidropdown(app.ChooseFlowFieldBPanel);
            app.FarmStateDropDownLabel_2 = uilabel(app.ChooseFlowFieldBPanel);
            app.FarmStateDropDownLabel_2.Text = 'Farm State';
            app.ffFarmStateDropDown_B = uidropdown(app.ChooseFlowFieldBPanel);
            app.ffFarmStateDropDown_B.Items = {'Optimised', 'Initial'};
            app.ffFarmStateDropDown_B.ItemsData = {'2', '1'};
            app.ffFarmStateDropDown_B.Value = '2';
            app.StartCaseDropDownLabel_2 = uilabel(app.ChooseFlowFieldBPanel);
            app.StartCaseDropDownLabel_2.Text = 'Start Case';
            app.ffStartCaseDropDown_B = uidropdown(app.ChooseFlowFieldBPanel);
            app.ChooseFlowFieldAPanel = uipanel(app.FlowFieldVisualisationPanel);
            app.ChooseFlowFieldAPanel.Title = 'Choose Flow Field A';
            app.SolverDropDownLabel = uilabel(app.ChooseFlowFieldAPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.ffSolverDropDown_A = uidropdown(app.ChooseFlowFieldAPanel);
            app.FarmStateDropDownLabel = uilabel(app.ChooseFlowFieldAPanel);
            app.FarmStateDropDownLabel.Text = 'Farm State';
            app.ffFarmStateDropDown_A = uidropdown(app.ChooseFlowFieldAPanel);
            app.ffFarmStateDropDown_A.Items = {'Initial', 'Optimised'};
            app.ffFarmStateDropDown_A.ItemsData = [1 2];
            app.ffFarmStateDropDown_A.Value = 1;
            app.StartCaseDropDownLabel = uilabel(app.ChooseFlowFieldAPanel);
            app.StartCaseDropDownLabel.Text = 'Start Case';
            app.ffStartCaseDropDown_A = uidropdown(app.ChooseFlowFieldAPanel);
            app.ffPlotTurbinesandEvaluateFlowFieldButton = uibutton(app.FlowFieldVisualisationPanel, 'push');
            app.ffPlotTurbinesandEvaluateFlowFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ffPlotTurbinesandEvaluateFlowFieldButtonPushed, true);
            app.ffPlotTurbinesandEvaluateFlowFieldButton.Text = {'Plot Turbines and'; 'Evaluate Flow Field'};
            app.ffresEditField = uieditfield(app.FlowFieldVisualisationPanel, 'numeric');
            app.ffresEditField.Limits = [1 2000];
            app.ffresEditField.Value = 200;
            app.SetResolutionofFlowFieldLabel = uilabel(app.FlowFieldVisualisationPanel);
            app.SetResolutionofFlowFieldLabel.Text = {'Set Resolution'; 'of Flow Field'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_results

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end