classdef AWFOM_solveropts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParticleSwarmPanel              matlab.ui.container.Panel
        SaveParticleSwarmSettingsButton  matlab.ui.control.Button
        ResettodefaultButton_2          matlab.ui.control.Button
        MinNeighboursFractionEditField  matlab.ui.control.NumericEditField
        MinNeighboursFractionEditFieldLabel  matlab.ui.control.Label
        SocialAdjustmentWeightEditField  matlab.ui.control.NumericEditField
        SocialAdjustmentWeightEditFieldLabel  matlab.ui.control.Label
        SelfAdjustmentWeightEditField   matlab.ui.control.NumericEditField
        SelfAdjustmentWeightEditFieldLabel  matlab.ui.control.Label
        MaxIterationsNnvarsEditField    matlab.ui.control.NumericEditField
        MaxIterationsEditField_2Label_2  matlab.ui.control.Label
        HybridFunctionDropDown          matlab.ui.control.DropDown
        HybridFunctionDropDownLabel     matlab.ui.control.Label
        patternsearchPanel              matlab.ui.container.Panel
        ResettodefaultButton_patternsearch_2  matlab.ui.control.Button
        ScaleMeshDropDown               matlab.ui.control.DropDown
        ScaleMeshDropDownLabel          matlab.ui.control.Label
        InitialMeshSizeEditField        matlab.ui.control.NumericEditField
        InitialMeshSizeEditFieldLabel   matlab.ui.control.Label
        SavepatternsearchSettingsButton  matlab.ui.control.Button
        MeshToleranceEditField          matlab.ui.control.NumericEditField
        MeshToleranceEditFieldLabel     matlab.ui.control.Label
        MaxIterationsEditField_patternsearch  matlab.ui.control.NumericEditField
        MaxIterationsEditField_2Label   matlab.ui.control.Label
        MaxFunctionEvaluationsEditField_patternsearch  matlab.ui.control.NumericEditField
        MaxFunctionEvaluationsEditField_2Label  matlab.ui.control.Label
        SearchMethodDropDown            matlab.ui.control.DropDown
        SearchMethodDropDownLabel       matlab.ui.control.Label
        MeshToleranceEditField_patternsearch  matlab.ui.control.NumericEditField
        ConstraintToleranceEditField_2Label  matlab.ui.control.Label
        AccelerateMeshDropDown          matlab.ui.control.DropDown
        AccelerateMeshDropDownLabel     matlab.ui.control.Label
        ResettodefaultButton_patternsearch  matlab.ui.control.Button
        fminconPanel                    matlab.ui.container.Panel
        SavefminconSettingsButton       matlab.ui.control.Button
        OptimalityToleranceEditField    matlab.ui.control.NumericEditField
        OptimalityToleranceEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel     matlab.ui.control.Label
        MaxFunctionEvaluationsEditField  matlab.ui.control.NumericEditField
        MaxFunctionEvaluationsEditFieldLabel  matlab.ui.control.Label
        FiniteDifferenceTypeDropDown    matlab.ui.control.DropDown
        FiniteDifferenceTypeDropDownLabel  matlab.ui.control.Label
        ConstraintToleranceEditField    matlab.ui.control.NumericEditField
        ConstraintToleranceEditFieldLabel  matlab.ui.control.Label
        CheckGradientsDropDown          matlab.ui.control.DropDown
        CheckGradientsDropDownLabel     matlab.ui.control.Label
        interiorpointonlyPanel          matlab.ui.container.Panel
        HessianApproximationDropDown    matlab.ui.control.DropDown
        HessianApproximationDropDownLabel  matlab.ui.control.Label
        BarrierParamUpdateDropDown      matlab.ui.control.DropDown
        BarrierParamUpdateDropDownLabel  matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        AlgorithmDropDownLabel          matlab.ui.control.Label
        ResettodefaultButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, use_fmincon, use_patternsearch, use_particleswarm, use_simulannealbnd, use_globalsearch, use_surrogateopt)
            if use_fmincon == 1
                app.fminconPanel.Enable = "on";
            else
                app.fminconPanel.Enable = "off";
            end


            if use_patternsearch == 1
                app.patternsearchPanel.Enable = "on";
            else
                app.patternsearchPanel.Enable = "off";
            end

            if use_particleswarm == 1
                app.patternsearchPanel.Enable = "on";
            else
                app.patternsearchPanel.Enable = "off";
            end
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            
            if strcmp(value, 'interior-point') == 1
                app.interiorpointonlyPanel.Enable = "on";
            else
                app.interiorpointonlyPanel.Enable = "off";
            end
        end

        % Button pushed function: SavefminconSettingsButton
        function SavefminconSettingsButtonPushed(app, event)
            load(".\optimisation\fmincon_options.mat", 'fmincon_options');

            
            fmincon_options.Algorithm = app.AlgorithmDropDown.Value;
            fmincon_options.CheckGradients = logical(app.CheckGradientsDropDown.Value);
            fmincon_options.ConstraintTolerance = app.ConstraintToleranceEditField.Value;           
            fmincon_options.FiniteDifferenceType = app.FiniteDifferenceTypeDropDown.Value;         
            fmincon_options.MaxFunctionEvaluations = app.MaxFunctionEvaluationsEditField.Value;
            fmincon_options.MaxIterations = app.MaxIterationsEditField.Value;            
            fmincon_options.OptimalityTolerance = app.OptimalityToleranceEditField.Value;

            if app.interiorpointonlyPanel.Enable == "on"
            fmincon_options.BarrierParamUpdate = app.BarrierParamUpdateDropDown.Value;
            fmincon_options.HessianApproximation = app.HessianApproximationDropDown.Value;
            end

            save(".\optimisation\fmincon_options.mat","fmincon_options");
        end

        % Button pushed function: SavepatternsearchSettingsButton
        function SavepatternsearchSettingsButtonPushed(app, event)
            load(".\optimisation\patternsearch_options.mat", 'patternsearch_options');

            patternsearch_options.InitialMeshSize = app.InitialMeshSizeEditField.Value;
            patternsearch_options.ScaleMesh = app.ScaleMeshDropDown.Value;
            patternsearch_options.AccelerateMesh = logical(app.AccelerateMeshDropDown.Value);
            patternsearch_options.ConstraintTolerance = app.MeshToleranceEditField_patternsearch.Value;              
            patternsearch_options.MaxFunctionEvaluations = app.MaxFunctionEvaluationsEditField_patternsearch.Value;
            patternsearch_options.MaxIterations = app.MaxIterationsEditField_patternsearch.Value;            
            patternsearch_options.MeshTolerance = app.MeshToleranceEditField.Value;
            
            if strcmp(app.SearchMethodDropDown.Value, '0') == 1
            patternsearch_options.SearchFcn = double.empty;
            else
            patternsearch_options.SearchFcn = app.SearchMethodDropDown.Value;
            end


            save(".\optimisation\patternsearch_options.mat","patternsearch_options");
        end

        % Button pushed function: SaveParticleSwarmSettingsButton
        function SaveParticleSwarmSettingsButtonPushed(app, event)
            load(".\optimisation\particleswarm_options.mat", 'particleswarm_options');
            load('params.mat', 'params');

            params.globcon.psMaxIter = app.MaxIterationsNnvarsEditField.Value;
            
            
            particleswarm_options.SelfAdjustmentWeight = app.SelfAdjustmentWeightEditField.Value;
            particleswarm_options.SocialAdjustmentWeight = app.SocialAdjustmentWeightEditField.Value;
            particleswarm_options.MinNeighborsFraction = app.MinNeighboursFractionEditField.Value;            

            
            if strcmp(app.HybridFunctionDropDown.Value, '(none)') == 1
            particleswarm_options.HybridFcn = double.empty;
            else
            particleswarm_options.HybridFcn = app.HybridFunctionDropDown.Value;
            end
                           
            save(".\optimisation\particleswarm_options.mat","particleswarm_options");
            save('params.mat', 'params');
        end

        % Button pushed function: ResettodefaultButton
        function ResettodefaultButtonPushed(app, event)
            fmincon_options = optimoptions('fmincon');
            save(".\optimisation\fmincon_options.mat","fmincon_options");
        end

        % Button pushed function: ResettodefaultButton_patternsearch_2
        function ResettodefaultButton_patternsearch_2Pushed(app, event)
            patternsearch_options = optimoptions('patternsearch');
            save(".\optimisation\patternsearch_options.mat","patternsearch_options");
        end

        % Button pushed function: ResettodefaultButton_2
        function ResettodefaultButton_2Pushed(app, event)
            particleswarm_options = optimoptions('particleswarm');
            save(".\optimisation\particleswarm_options.mat","particleswarm_options");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.fminconPanel = uipanel(app.UIFigure);
            app.fminconPanel.Title = 'fmincon';
            app.ResettodefaultButton = uibutton(app.fminconPanel, 'push');
            app.ResettodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButtonPushed, true);
            app.ResettodefaultButton.Text = 'Reset to default';
            app.AlgorithmDropDownLabel = uilabel(app.fminconPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.fminconPanel);
            app.AlgorithmDropDown.Items = {'interior-point', 'sqp', 'active-set'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'interior-point';
            app.interiorpointonlyPanel = uipanel(app.fminconPanel);
            app.interiorpointonlyPanel.Title = 'interior-point only';
            app.BarrierParamUpdateDropDownLabel = uilabel(app.interiorpointonlyPanel);
            app.BarrierParamUpdateDropDownLabel.Text = 'BarrierParamUpdate';
            app.BarrierParamUpdateDropDown = uidropdown(app.interiorpointonlyPanel);
            app.BarrierParamUpdateDropDown.Items = {'monotone', 'predictor-corrector'};
            app.BarrierParamUpdateDropDown.Value = 'monotone';
            app.HessianApproximationDropDownLabel = uilabel(app.interiorpointonlyPanel);
            app.HessianApproximationDropDownLabel.Text = 'HessianApproximation';
            app.HessianApproximationDropDown = uidropdown(app.interiorpointonlyPanel);
            app.HessianApproximationDropDown.Items = {'bfgs', 'finite-difference', 'lbfgs'};
            app.HessianApproximationDropDown.Value = 'bfgs';
            app.CheckGradientsDropDownLabel = uilabel(app.fminconPanel);
            app.CheckGradientsDropDownLabel.Text = 'CheckGradients';
            app.CheckGradientsDropDown = uidropdown(app.fminconPanel);
            app.CheckGradientsDropDown.Items = {'false', 'true'};
            app.CheckGradientsDropDown.ItemsData = {'0', '1'};
            app.CheckGradientsDropDown.Value = '0';
            app.ConstraintToleranceEditFieldLabel = uilabel(app.fminconPanel);
            app.ConstraintToleranceEditFieldLabel.Text = 'ConstraintTolerance';
            app.ConstraintToleranceEditField = uieditfield(app.fminconPanel, 'numeric');
            app.ConstraintToleranceEditField.Limits = [0 Inf];
            app.ConstraintToleranceEditField.Value = 1e-06;
            app.FiniteDifferenceTypeDropDownLabel = uilabel(app.fminconPanel);
            app.FiniteDifferenceTypeDropDownLabel.Text = 'FiniteDifferenceType';
            app.FiniteDifferenceTypeDropDown = uidropdown(app.fminconPanel);
            app.FiniteDifferenceTypeDropDown.Items = {'forward', 'central'};
            app.FiniteDifferenceTypeDropDown.Value = 'forward';
            app.MaxFunctionEvaluationsEditFieldLabel = uilabel(app.fminconPanel);
            app.MaxFunctionEvaluationsEditFieldLabel.Text = 'MaxFunctionEvaluations';
            app.MaxFunctionEvaluationsEditField = uieditfield(app.fminconPanel, 'numeric');
            app.MaxFunctionEvaluationsEditField.Limits = [0 Inf];
            app.MaxFunctionEvaluationsEditField.RoundFractionalValues = 'on';
            app.MaxFunctionEvaluationsEditField.Value = 3000;
            app.MaxIterationsEditFieldLabel = uilabel(app.fminconPanel);
            app.MaxIterationsEditFieldLabel.Text = 'MaxIterations';
            app.MaxIterationsEditField = uieditfield(app.fminconPanel, 'numeric');
            app.MaxIterationsEditField.Limits = [0 Inf];
            app.MaxIterationsEditField.RoundFractionalValues = 'on';
            app.MaxIterationsEditField.Value = 1000;
            app.OptimalityToleranceEditFieldLabel = uilabel(app.fminconPanel);
            app.OptimalityToleranceEditFieldLabel.Text = 'OptimalityTolerance';
            app.OptimalityToleranceEditField = uieditfield(app.fminconPanel, 'numeric');
            app.OptimalityToleranceEditField.Limits = [0 Inf];
            app.OptimalityToleranceEditField.Value = 1e-06;
            app.SavefminconSettingsButton = uibutton(app.fminconPanel, 'push');
            app.SavefminconSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavefminconSettingsButtonPushed, true);
            app.SavefminconSettingsButton.Text = 'Save fmincon Settings';
            app.patternsearchPanel = uipanel(app.UIFigure);
            app.patternsearchPanel.Title = 'patternsearch';
            app.ResettodefaultButton_patternsearch = uibutton(app.patternsearchPanel, 'push');
            app.ResettodefaultButton_patternsearch.Text = 'Reset to default';
            app.AccelerateMeshDropDownLabel = uilabel(app.patternsearchPanel);
            app.AccelerateMeshDropDownLabel.Text = 'AccelerateMesh';
            app.AccelerateMeshDropDown = uidropdown(app.patternsearchPanel);
            app.AccelerateMeshDropDown.Items = {'false', 'true'};
            app.AccelerateMeshDropDown.ItemsData = {'0', '1'};
            app.AccelerateMeshDropDown.Value = '0';
            app.ConstraintToleranceEditField_2Label = uilabel(app.patternsearchPanel);
            app.ConstraintToleranceEditField_2Label.Text = 'ConstraintTolerance';
            app.MeshToleranceEditField_patternsearch = uieditfield(app.patternsearchPanel, 'numeric');
            app.MeshToleranceEditField_patternsearch.Limits = [0 Inf];
            app.MeshToleranceEditField_patternsearch.Value = 1e-06;
            app.SearchMethodDropDownLabel = uilabel(app.patternsearchPanel);
            app.SearchMethodDropDownLabel.Text = 'SearchMethod';
            app.SearchMethodDropDown = uidropdown(app.patternsearchPanel);
            app.SearchMethodDropDown.Items = {'No search method (default)', 'GPSPositiveBasis2N', 'GPSPositiveBasisNp1', 'GSSPositiveBasis2N', 'GSSPositiveBasisNp1', 'MADSPositiveBasis2N', 'MADSPositiveBasisNp1', 'searchga', 'searchlhs', 'searchneldermead'};
            app.SearchMethodDropDown.ItemsData = {'0', 'GPSPositiveBasis2N', 'GPSPositiveBasisNp1', 'GSSPositiveBasis2N', 'GSSPositiveBasisNp1', 'MADSPositiveBasis2N', 'MADSPositiveBasisNp1', 'searchga', 'searchlhs', 'searchneldermead'};
            app.SearchMethodDropDown.Value = '0';
            app.MaxFunctionEvaluationsEditField_2Label = uilabel(app.patternsearchPanel);
            app.MaxFunctionEvaluationsEditField_2Label.Text = 'MaxFunctionEvaluations';
            app.MaxFunctionEvaluationsEditField_patternsearch = uieditfield(app.patternsearchPanel, 'numeric');
            app.MaxFunctionEvaluationsEditField_patternsearch.Limits = [0 Inf];
            app.MaxFunctionEvaluationsEditField_patternsearch.RoundFractionalValues = 'on';
            app.MaxFunctionEvaluationsEditField_patternsearch.Value = 3000;
            app.MaxIterationsEditField_2Label = uilabel(app.patternsearchPanel);
            app.MaxIterationsEditField_2Label.Text = 'MaxIterations';
            app.MaxIterationsEditField_patternsearch = uieditfield(app.patternsearchPanel, 'numeric');
            app.MaxIterationsEditField_patternsearch.Limits = [0 Inf];
            app.MaxIterationsEditField_patternsearch.RoundFractionalValues = 'on';
            app.MaxIterationsEditField_patternsearch.Value = 1000;
            app.MeshToleranceEditFieldLabel = uilabel(app.patternsearchPanel);
            app.MeshToleranceEditFieldLabel.Text = 'MeshTolerance';
            app.MeshToleranceEditField = uieditfield(app.patternsearchPanel, 'numeric');
            app.MeshToleranceEditField.Limits = [0 Inf];
            app.MeshToleranceEditField.Value = 1e-06;
            app.SavepatternsearchSettingsButton = uibutton(app.patternsearchPanel, 'push');
            app.SavepatternsearchSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavepatternsearchSettingsButtonPushed, true);
            app.SavepatternsearchSettingsButton.Text = 'Save patternsearch Settings';
            app.InitialMeshSizeEditFieldLabel = uilabel(app.patternsearchPanel);
            app.InitialMeshSizeEditFieldLabel.Text = 'InitialMeshSize';
            app.InitialMeshSizeEditField = uieditfield(app.patternsearchPanel, 'numeric');
            app.InitialMeshSizeEditField.Limits = [0 1];
            app.InitialMeshSizeEditField.Value = 1;
            app.ScaleMeshDropDownLabel = uilabel(app.patternsearchPanel);
            app.ScaleMeshDropDownLabel.Text = 'ScaleMesh';
            app.ScaleMeshDropDown = uidropdown(app.patternsearchPanel);
            app.ScaleMeshDropDown.Items = {'on', 'off'};
            app.ScaleMeshDropDown.Value = 'on';
            app.ResettodefaultButton_patternsearch_2 = uibutton(app.patternsearchPanel, 'push');
            app.ResettodefaultButton_patternsearch_2.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButton_patternsearch_2Pushed, true);
            app.ResettodefaultButton_patternsearch_2.Text = 'Reset to default';
            app.ParticleSwarmPanel = uipanel(app.UIFigure);
            app.ParticleSwarmPanel.Title = 'Particle Swarm';
            app.HybridFunctionDropDownLabel = uilabel(app.ParticleSwarmPanel);
            app.HybridFunctionDropDownLabel.Text = 'Hybrid Function';
            app.HybridFunctionDropDown = uidropdown(app.ParticleSwarmPanel);
            app.HybridFunctionDropDown.Items = {'(none)', 'fmincon', 'patternsearch'};
            app.HybridFunctionDropDown.Value = '(none)';
            app.MaxIterationsEditField_2Label_2 = uilabel(app.ParticleSwarmPanel);
            app.MaxIterationsEditField_2Label_2.Text = 'MaxIterations (N*nvars)';
            app.MaxIterationsNnvarsEditField = uieditfield(app.ParticleSwarmPanel, 'numeric');
            app.MaxIterationsNnvarsEditField.Limits = [0 Inf];
            app.MaxIterationsNnvarsEditField.RoundFractionalValues = 'on';
            app.MaxIterationsNnvarsEditField.Value = 200;
            app.SelfAdjustmentWeightEditFieldLabel = uilabel(app.ParticleSwarmPanel);
            app.SelfAdjustmentWeightEditFieldLabel.Text = 'SelfAdjustmentWeight';
            app.SelfAdjustmentWeightEditField = uieditfield(app.ParticleSwarmPanel, 'numeric');
            app.SelfAdjustmentWeightEditField.Value = 1.49;
            app.SocialAdjustmentWeightEditFieldLabel = uilabel(app.ParticleSwarmPanel);
            app.SocialAdjustmentWeightEditFieldLabel.Text = 'SocialAdjustmentWeight';
            app.SocialAdjustmentWeightEditField = uieditfield(app.ParticleSwarmPanel, 'numeric');
            app.SocialAdjustmentWeightEditField.Value = 1.49;
            app.MinNeighboursFractionEditFieldLabel = uilabel(app.ParticleSwarmPanel);
            app.MinNeighboursFractionEditFieldLabel.Text = 'MinNeighboursFraction';
            app.MinNeighboursFractionEditField = uieditfield(app.ParticleSwarmPanel, 'numeric');
            app.MinNeighboursFractionEditField.Limits = [0 1];
            app.MinNeighboursFractionEditField.Value = 0.25;
            app.ResettodefaultButton_2 = uibutton(app.ParticleSwarmPanel, 'push');
            app.ResettodefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResettodefaultButton_2Pushed, true);
            app.ResettodefaultButton_2.Text = 'Reset to default';
            app.SaveParticleSwarmSettingsButton = uibutton(app.ParticleSwarmPanel, 'push');
            app.SaveParticleSwarmSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParticleSwarmSettingsButtonPushed, true);
            app.SaveParticleSwarmSettingsButton.Text = 'Save Particle Swarm Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWFOM_solveropts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end